classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Tab                          matlab.ui.container.Tab
        ListadesealesgeneradasPanel  matlab.ui.container.Panel
        ListBox                      matlab.ui.control.ListBox
        ListBoxLabel                 matlab.ui.control.Label
        ReproducirButton             matlab.ui.control.Button
        ImportarsealPanel            matlab.ui.container.Panel
        ImportarButton               matlab.ui.control.Button
        GenerarsealPanel             matlab.ui.container.Panel
        GenerarButton                matlab.ui.control.Button
        TipodesealDropDown           matlab.ui.control.DropDown
        TipodesealDropDownLabel      matlab.ui.control.Label
        CapturasealPanel             matlab.ui.container.Panel
        GuardarButton                matlab.ui.control.Button
        GrabarButton                 matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        Tab2                         matlab.ui.container.Tab
    end


    properties (Access = public)
        x_in % Señal de audio
        sample_rate % Frecuencia de muestreo
        valor % Opción seleccionada en el dropdown
    end

    properties (Access = private)
    signalsData = {};  % Cell array to store signals and sample rates
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
              [app.x_in, app.sample_rate] = grabar_pushed_button(app.UIAxes);
        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
            disp(['Sample rate: ', num2str(app.sample_rate)]);
             reproducir_pushed_button(app.x_in, app.sample_rate);
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            % Ingresar los parámetros para grabar el audio
            prompt = {'Nombre del archivo:'};
            dlgtitle = 'Guardar archivo';
            dims = [1 35];
            definput = {'archivo'};
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            
            % Recuperar parámetros de la caja de diálogo
            nombre_archivo = (answer{1});

            nombre_archivo_completo = [nombre_archivo, '.wav'];
            disp(nombre_archivo_completo);
            audiowrite(nombre_archivo_completo, app.x_in, app.sample_rate);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: ImportarButton
        function ImportarButtonPushed(app, event)
            [filename, path] = uigetfile({'*.mp3;*.wav'});
            figure(app.UIFigure);
            fullFileName = fullfile(path, filename);  % Construye la ruta completa del archivo
            
            % Recuperar los datos importados
            [app.x_in, app.sample_rate] = audioread(fullFileName);
            % sound(data, sample_rate);

            % Graficar Canal 1 en UIAxes de App Designer
            plot(app.UIAxes, app.x_in(:,1), 'v');
            title(app.UIAxes, 'Señal Estereofónica: Canal 1');
            xlabel(app.UIAxes, 'n');
            grid(app.UIAxes, 'on');
            add_to_signals(app, app.x_in, app.sample_rate,3);
            
        end

        % Value changed function: TipodesealDropDown
        function TipodesealDropDownValueChanged(app, event)
            app.valor = app.TipodesealDropDown.Value;
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            [app.x_in, app.sample_rate] = generar_funciones(app.UIAxes, app.valor);
            % Suppose 'signal' is your generated/recorded signal and 'Fs' is the sample rate
            % Increment the signal count and add to the cell array
            num_signals = length(app.signalsData) + 1;
            app.signalsData{num_signals} = {app.x_in, app.sample_rate};
            
            % Add an entry to the ListBox to represent this signal
            signalName = ['Signal ', num2str(num_signals)];  % Name the signal (e.g., "Signal 1", "Signal 2")
            app.ListBox.Items{num_signals} = signalName;     % Add to the List Box

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            % Callback for when a signal is selected from the List Box
            selectedSignalIndex = app.ListBox.Value;  % Get the selected index
            signalData = app.signalsData{selectedSignalIndex};  % Retrieve signal and sample rate
        
            % 'signalData' contains {signal, sample rate}
            selectedSignal = signalData{1};  % The actual signal data
            selectedSampleRate = signalData{2};  % The sample rate
            
            % Now you can do something with the selected signal and sample rate, like playing it
            sound(selectedSignal, selectedSampleRate);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.CapturasealPanel = uipanel(app.Tab);
            app.CapturasealPanel.Title = 'Captura señal';
            app.GrabarButton = uibutton(app.CapturasealPanel, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.GuardarButton = uibutton(app.CapturasealPanel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.GenerarsealPanel = uipanel(app.Tab);
            app.GenerarsealPanel.Title = 'Generar señal';
            app.TipodesealDropDownLabel = uilabel(app.GenerarsealPanel);
            app.TipodesealDropDownLabel.Text = 'Tipo de señal';
            app.TipodesealDropDown = uidropdown(app.GenerarsealPanel);
            app.TipodesealDropDown.Items = {'---', 'Chirp', 'Seno', 'Coseno', 'Diente de sierra', 'Rampa', 'Escalón'};
            app.TipodesealDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodesealDropDownValueChanged, true);
            app.TipodesealDropDown.Value = '---';
            app.GenerarButton = uibutton(app.GenerarsealPanel, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.ImportarsealPanel = uipanel(app.Tab);
            app.ImportarsealPanel.Title = 'Importar señal';
            app.ImportarButton = uibutton(app.ImportarsealPanel, 'push');
            app.ImportarButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarButtonPushed, true);
            app.ImportarButton.Text = 'Importar';
            app.ReproducirButton = uibutton(app.Tab, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.ListadesealesgeneradasPanel = uipanel(app.Tab);
            app.ListadesealesgeneradasPanel.Title = 'Lista de señales generadas';
            app.ListBoxLabel = uilabel(app.ListadesealesgeneradasPanel);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.ListadesealesgeneradasPanel);
            app.ListBox.Items = {''};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = '';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end